classdef marmoset_mouse_region_compare_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StatusLamp                     matlab.ui.control.Lamp
        StatusLampLabel                matlab.ui.control.Label
        MarmosetMatchingRegionsTextArea  matlab.ui.control.TextArea
        MarmosetMatchingRegionsTextAreaLabel  matlab.ui.control.Label
        MouseMatchingRegionsTextArea   matlab.ui.control.TextArea
        MouseMatchingRegionsTextAreaLabel  matlab.ui.control.Label
        MarmosetRegionTextArea         matlab.ui.control.TextArea
        MarmosetRegionTextAreaLabel    matlab.ui.control.Label
        MouseRegionTextArea            matlab.ui.control.TextArea
        MouseRegionTextAreaLabel       matlab.ui.control.Label
        SelectregionDropDown_marmoset  matlab.ui.control.DropDown
        SelectregionDropDown_2Label    matlab.ui.control.Label
        SelectregionDropDown_mouse     matlab.ui.control.DropDown
        SelectregionDropDownLabel      matlab.ui.control.Label
        MarmosetLabel                  matlab.ui.control.Label
        MouseLabel                     matlab.ui.control.Label
        MarmosetHierarchy              matlab.ui.control.UIAxes
        MouseHierarchy                 matlab.ui.control.UIAxes
        MarmosetSection                matlab.ui.control.UIAxes
        MouseSection                   matlab.ui.control.UIAxes
        Marmoset3Dview                 matlab.ui.control.UIAxes
        Mouse3Dview                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % %         global LUT region mouselist marmosetlist specieslist % cast as global variables
        %         load brainmatchatlas LUT % lookup table (LUT)
        %         load mouseregionlist mouselist  % mouselist
        %         load marmosetregionlist marmosetlist % marmosetlist % Description
        mouseregion % Description
        marmosetregion
        L % region indices in LUT
    end

    properties (Access = public)
        atlases = load('atlas_for_vis'); % Description
        LUT = load('brainmatchatlas.mat','LUT') % lookup table (LUT)
        mouselist = load('mouseregionlist','mouselist'); % mouselist
        marmosetlist = load('marmosetregionlist','marmosetlist'); % marmosetlist % Description
    end


    methods (Access = public)

        %         function results = search_in_LUT(app,ifdescend,speciesinfo)
        %             regions=app.MouseEditField.Value;
        %             if speciesinfo == 1
        %             load data/mouseregionlist.mat mouselist
        %             end
        %             results = mouselist;
        %
        %         end

    end

    methods (Access = private)

        function CC = allchildren(app,animalist,id)
            %             animalist=app.mouselist.mouselist;
            CC=[];
            topid=animalist{1,4}; % tree root. No need to run
            for i=1:length(id)
                if id(i)==topid
                    C=animalist(1,:);
                else
                    C=childreninfo(app,animalist,id(i));
                end
                Ci=cell2mat(C(:,4));
                CC=[CC;Ci];
            end
        end


        function regiontree = treeposition(app,animalist,regionid)
            parentfullinfo=lineageinfo(app,animalist,regionid);
            childfullinfo=childreninfo(app,animalist,regionid);
            regiontree=[parentfullinfo(1:end-1,:);childfullinfo];
        end

        function parentfullinfo = lineageinfo(app,animalist,regionid)
            idlist=cell2mat(animalist(:,4));
            idind=find(idlist==regionid);
            regioninfo=animalist(idind,:);
            regionlayer=animalist{idind,1};
            parentfullinfo=regioninfo;
            for L=1:regionlayer-1
                parentid=regioninfo{6};
                parentind=find(idlist==parentid);
                regioninfo=animalist(parentind,:);
                parentlayer=regioninfo{1};
                parentfullinfo=[regioninfo;parentfullinfo];
            end
        end

        function childfullinfo = childreninfo(app,animalist,id)
            % identify children
            regionids=cell2mat(animalist(:,4));
            ind=find(regionids==id);
            regioninfo=animalist(ind(1),:);
            childfullinfo=regioninfo;
            % regioninfo=animallist(ind,:);
            parentids=cell2mat(animalist(:,6));
            chind=find(parentids==id); % first layer down
            % get all layers
            while ~isempty(chind) % descend
                regioninfo=animalist(chind,:);
                childfullinfo=[childfullinfo;regioninfo];
                ind=[ind;chind];
                gchind=[];
                for c=1:length(chind)
                    id=regionids(chind(c));
                    gchind=[gchind;find(parentids==id)];
                end
                chind=gchind;
            end
        end

        function G = BGplottree(app,animalist,regionid)
            CC=[];
            for i=1:length(regionid)
                C=treeposition(app,animalist,regionid(i));
                CC=[CC;C];
            end
            [treenodes,regionids]=treeplotgen(app,CC);
            regioninfo=getregioninfo(app,animalist,regionid);
            regionlist=getregioninfo(app,animalist,regionids);
            AdjMat=zeros(length(treenodes),length(treenodes));
            for i=1:length(treenodes)
                AdjMat(i,treenodes(i))=1;
                AdjMat(treenodes(i),i)=1;
            end
            G=graph(AdjMat,regionlist(:,2));
            G=rmedge(G,1:numnodes(G),1:numnodes(G));

        end


        function [treenodes,regionids] = treeplotgen(app,regionlist)
            % remove repeats
            regionids=cell2mat(regionlist(:,4));
            [~,ia]=unique(regionids);
            regionlist=regionlist(ia,:);
            %%
            regionids=cell2mat(regionlist(:,4));
            parentids=cell2mat(regionlist(:,6));
            % regionids should be inclusive of parentids
            uparentids=unique(parentids);
            idcheck=ismember(uparentids,unique(regionids));
            if sum(idcheck==0)>0
                % add the missing parent
                regionids=[uparentids(idcheck==0);regionids];
                parentids=[uparentids(idcheck==0);parentids];
            end
            Nregions=size(regionids,1);
            treenodes=zeros(1,Nregions);
            for n=1:Nregions
                treenodes(n)=find(regionids==parentids(n)); % each entry correspond to its parent
            end
        end

        function regioninfo = getregioninfo(app,animalist,idlist)
            regionids=cell2mat(animalist(:,4));
            ind=zeros(length(idlist),1);
            regioninfo=cell(length(idlist),size(animalist,2));
            for i=1:length(idlist)
                idind=find(regionids==idlist(i));
                ind(i)=idind;
                regioninfo(i,:)=animalist(ind(i),:);
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Mouse3Dview, MouseMatchingRegionsTextArea, 
        % ...and 2 other components
        function MouseRegionTextValueChanging(app, event)
            %% identify region from dropdown list
            changingValue = event.Value;
            app.mouseregion = changingValue;
            app.MouseRegionTextArea.Value = app.mouseregion;
            acronymlist=app.mouselist.mouselist(:,2);
            ind=find(strcmp(acronymlist,app.mouseregion)); % index in mouselist
            mouseid=cell2mat(app.mouselist.mouselist(ind,4));
            app.MouseMatchingRegionsTextArea.Value=app.mouselist.mouselist{ind,3};
            app.StatusLamp.Color='y';
            drawnow()
            %% look for matching areas in LUT
            app.SelectregionDropDown_marmoset.Value="";
            app.MarmosetRegionTextArea.Value="";
            animalLUT = app.LUT.LUT.mouse;
            %             animalist = app.mouselist.mouselist;
            Ls=cell(length(mouseid),1);
            % function "LUTleaves" in "search_in_LUT.m"
            N=size(animalLUT,1);
            loc=zeros(N,1);
            for i=1:N
                if ~isempty(animalLUT{i})
                    % get all children regions of each entry (row) in LUT
                    CC = allchildren(app,app.mouselist.mouselist,animalLUT{i});
                    loc(i)=sum(ismember(mouseid,CC)); % search for regionid within all LUT entries
                end
            end
            L=find(loc);
            if isempty(L) % if didn't find in LUT, search children regions of the mouse region
                RR=allchildren(app,app.mouselist.mouselist,mouseid);
                RL=cell(length(RR),1);
                for r=1:length(RR) % search each subregion
                    % function "LUTleaves" in "search_in_LUT.m"
                    %                         RL{r}=LUTleaves(primarylist,primaryLUT,RR(r));
                    N=size(animalLUT,1);
                    loc=zeros(N,1);
                    for i=1:N
                        if ~isempty(animalLUT{i})
                            % get all children regions of each entry (row) in LUT
                            CC = allchildren(app,app.mouselist.mouselist,animalLUT{i});
                            loc(i)=sum(ismember(RR(r),CC)); % search for regionid within all LUT entries
                        end
                    end
                    RL{r}=find(loc);
                end
                L=cell2mat(RL);
                if isempty(L)
                    app.MarmosetMatchingRegionsTextArea.Value= 'Error: No matching area.';
                else
                    L=unique(L);
                    Ls{i}=L;
                end
            else
                Ls{i}=L;
            end
            Ls=cell2mat(Ls);
            app.L=Ls;
            %% display matching areas
            searchlist=app.marmosetlist.marmosetlist;
            searchLUT=app.LUT.LUT.marmoset;
            searchid=cell2mat(searchLUT(Ls,1)');
            if ~isempty(searchid)
                %     searchregioninfo=getregioninfo(searchlist,searchid);
                marmosetids=cell2mat(searchlist(:,4));
                marmosetind=zeros(length(searchid),1);
                marmosetregioninfo=cell(length(searchid),size(searchlist,2));
                for i=1:length(searchid)
                    idind=find(marmosetids==searchid(i));
                    if isempty(idind)
                        app.MarmosetMatchingRegionsTextArea.Value= 'Error: Invalid region ID.';
                    elseif length(idind)>1
                        app.MarmosetMatchingRegionsTextArea.Value= 'Error: Double check region list.';
                    else
                        marmosetind(i)=idind;
                        marmosetregioninfo(i,:)=searchlist(marmosetind(i),:);
                    end
                end
                % display the names
                app.MarmosetRegionTextArea.Value=join(marmosetregioninfo(:,2),',',2);
                app.MarmosetMatchingRegionsTextArea.Value= join(marmosetregioninfo(:,3),',',2);
                %                 searchid1=[];
                %                 for li=1:length(Ls)
                %                     searchid1l=allchildren(searchlist,searchLUT{L(li),1});
                %                     searchid1l=nonzeros(searchid1l);
                %                     if ~isempty(searchid1l)
                %                         searchid1l(:,2)=li;
                %                         searchid1=[searchid1;searchid1l];
                %                     end
                %                 end
            else
                app.MarmosetMatchingRegionsTextArea.Value= 'Error: No matching area.';
            end
            %% Show region in 3D view (mouse)
            cla(app.Mouse3Dview)
            cla(app.MouseSection)
            cla(app.MouseHierarchy)
            %% Display the brain
            brainvol = app.atlases.mouseanno;
            [X,Y,Z]=size(brainvol);
            [XX,YY,ZZ]=meshgrid(1:Y,1:X,1:Z);
            V=brainvol>0;
            fv=isosurface(V*2,1);
            patch(app.Mouse3Dview,fv,'EdgeColor','none','FaceColor',[.9 .9 .9],'FaceVertexAlphaData',.3,'FaceAlpha','flat','AlphaDataMapping','none');
            axis(app.Mouse3Dview,'tight')
            camlight(app.Mouse3Dview,'right')
            lighting(app.Mouse3Dview,'gouraud')
            daspect(app.Mouse3Dview,app.atlases.mouseres)
            %% highlight identified areas
            % from "match_brain_view.m"
            % to control colors, go through each row in LUT
            mouseidlist1=[];
            for li=1:length(mouseid)
                mouseidlist1l=allchildren(app,app.mouselist.mouselist,mouseid);
                mouseidlist1l=nonzeros(mouseidlist1l);
                if ~isempty(mouseidlist1l)
                    mouseidlist1l(:,2)=li;
                    mouseidlist1=[mouseidlist1;mouseidlist1l];
                end
            end
            regioncolors=lines;
            % show individual brain regions and assign colors on top of the 3D brain
            idV=zeros(size(app.atlases.mouseanno));
            for i=1:length(mouseidlist1)
                tmpvol=ismember(app.atlases.mouseanno,mouseidlist1(i));
                idV=idV+tmpvol*i; % one index for each region
            end
            ifanno=sum(sum(sum(idV)));
            if ifanno>0
                n=0;
                for i=1:length(mouseidlist1)
                    n=n+1;
                    %                     brainvis3d(idV==i,h,regioncolors(n,:),mouseres);
                    V=idV==i;
                    fv1=isosurface(V*2,1);
                    patch(app.Mouse3Dview,fv1,'EdgeColor','none','FaceColor',regioncolors(n,:),'FaceVertexAlphaData',.3,'FaceAlpha','flat','AlphaDataMapping','none');
                end
                %% display coronal section

                K1=squeeze(sum(sum(idV,1),2)); % get total voxels for all Z
                [~,K1i]=max(K1); % find the section with largest area annotated
                imagesc(app.MouseSection,app.atlases.mousetemplate(:,:,K1i)')
                colormap(app.MouseSection,'gray')
                hold(app.MouseSection,'on')
                line(app.MouseSection, [10,10+5/app.atlases.mouseres(1)],[10 10],'linewidth',2,'color','w') % scale bar of 5mm
                text(app.MouseSection,10,5,'5mm','color','w')
                idmask=idV(:,:,K1i)';
                %% show individual brain regions and assign colors on top of the
                % coronal section
                n=0;
                for i=1:length(mouseidlist1)
                    n=n+1;
                    %                     transparent_overlay(gca,idmask==i,regioncolors(n,:));
                    overimg=idmask==i;
                    overimg=single(overimg)/single(max(max(overimg)));
                    mask=cat(3,ones(size(overimg))*regioncolors(n,1),ones(size(overimg))*regioncolors(n,2),ones(size(overimg))*regioncolors(n,3));
                    hold(app.MouseSection,'on');
                    h=imagesc(app.MouseSection,mask);
                    set(h,'AlphaData',single(overimg)*.5)
                end
                axis(app.MouseSection,'image','off')

            end
            %% Show matching 3D view (marmoset)
            cla(app.Marmoset3Dview)
            cla(app.MarmosetSection)
            cla(app.MarmosetHierarchy)
            %% Display the brain
            brainvol = app.atlases.marmosetanno2;
            [X,Y,Z]=size(brainvol);
            [XX,YY,ZZ]=meshgrid(1:Y,1:X,1:Z);
            V=brainvol>0;
            fv=isosurface(V*2,1);
            patch(app.Marmoset3Dview,fv,'EdgeColor','none','FaceColor',[.9 .9 .9],'FaceVertexAlphaData',.3,'FaceAlpha','flat','AlphaDataMapping','none');
            axis(app.Marmoset3Dview,'tight')
            camlight(app.Marmoset3Dview,'right')
            lighting(app.Marmoset3Dview,'gouraud')
            daspect(app.Marmoset3Dview,app.atlases.marmosetres)
            %% highlight identified areas
            % from "match_brain_view.m"
            % to control colors, go through each row in LUT
            if ~contains(app.MarmosetMatchingRegionsTextArea.Value,'Error')
                marmosetidlist1=[];
                for li=1:length(app.L)
                    marmosetmatchid=app.LUT.LUT.marmoset{app.L(li),1};
                    if ~isempty(marmosetmatchid)
                        marmosetidlist1l=allchildren(app,app.marmosetlist.marmosetlist,marmosetmatchid);
                        marmosetidlist1l=nonzeros(marmosetidlist1l);
                        if ~isempty(marmosetidlist1l)
                            marmosetidlist1l(:,2)=li;
                            marmosetidlist1=[marmosetidlist1;marmosetidlist1l];
                        end
                    end
                end
                regioncolors=lines;
                % show individual brain regions and assign colors on top of the 3D brain
                idV=zeros(size(app.atlases.marmosetanno2));
                for i=1:length(marmosetidlist1)
                    tmpvol=ismember(app.atlases.marmosetanno2,marmosetidlist1(i));
                    idV=idV+tmpvol*i; % one index for each region
                end
                ifanno=sum(sum(sum(idV)));

                if ifanno>0
                    n=0;
                    for i=1:length(marmosetidlist1)
                        n=n+1;
                        %                     brainvis3d(idV==i,h,regioncolors(n,:),mouseres);
                        V=idV==i;
                        fv1=isosurface(V*2,1);
                        patch(app.Marmoset3Dview,fv1,'EdgeColor','none','FaceColor',regioncolors(n,:),'FaceVertexAlphaData',.3,'FaceAlpha','flat','AlphaDataMapping','none');
                    end
                    %% display coronal section

                    K1=squeeze(sum(sum(idV,1),2)); % get total voxels for all Z
                    [~,K1i]=max(K1); % find the section with largest area annotated
                    imagesc(app.MarmosetSection,app.atlases.marmosettemplate2(:,:,K1i)')
                    colormap(app.MarmosetSection,'gray')
                    hold(app.MarmosetSection,'on')
                    line(app.MarmosetSection, [10,10+5/app.atlases.marmosetres(1)],[10 10],'linewidth',2,'color','w') % scale bar of 5mm
                    text(app.MarmosetSection,10,5,'5mm','color','w')
                    idmask=idV(:,:,K1i)';
                    %% show individual brain regions and assign colors on top of the
                    % coronal section
                    n=0;
                    for i=1:length(mouseidlist1)
                        n=n+1;
                        %                     transparent_overlay(gca,idmask==i,regioncolors(n,:));
                        overimg=idmask==i;
                        overimg=single(overimg)/single(max(max(overimg)));
                        mask=cat(3,ones(size(overimg))*regioncolors(n,1),ones(size(overimg))*regioncolors(n,2),ones(size(overimg))*regioncolors(n,3));
                        hold(app.MarmosetSection,'on');
                        h=imagesc(app.MarmosetSection,mask);
                        set(h,'AlphaData',single(overimg)*.5)
                    end
                    axis(app.MarmosetSection,'image','off')
                end
            end
            %% show hierarchy (mouse)
            if ~isempty(app.MouseRegionTextArea.Value)
                %                   mouseidL=cell2mat(app.LUT.LUT.mouse(app.L,1)');
                G1=BGplottree(app,app.mouselist.mouselist,mouseid);
                h1=plot(app.MouseHierarchy,G1,'Layout','layered');
                h1.NodeFontSize=12;
                h1.LineWidth=2;
                highlight(h1,app.MouseRegionTextArea.Value,'Nodecolor','r')
                highlight(h1,app.MouseRegionTextArea.Value,'NodeLabelColor','r')
            end
            %% show hierarchy (marmoset)
            if ~contains(app.MarmosetMatchingRegionsTextArea.Value,'Error')
                marmosetidL=cell2mat(app.LUT.LUT.marmoset(app.L,1)');
                G2=BGplottree(app,app.marmosetlist.marmosetlist,marmosetidL);
                h2=plot(app.MarmosetHierarchy,G2,'Layout','layered');
                h2.NodeFontSize=12;
                h2.LineWidth=2;
                highlight(h2,app.MarmosetRegionTextArea.Value,'Nodecolor','r')
                highlight(h2,app.MarmosetRegionTextArea.Value,'NodeLabelColor','r')
            end
            app.StatusLamp.Color='g';
        end

        % Callback function: Marmoset3Dview, 
        % ...and 3 other components
        function MarmosetRegionTextValueChanging(app, event)
            %% identify region from dropdown list
            changingValue = event.Value;
            app.marmosetregion = changingValue;
            app.MarmosetRegionTextArea.Value = app.marmosetregion;
            acronymlist=app.marmosetlist.marmosetlist(:,2);
            ind=find(strcmp(acronymlist,app.marmosetregion)); % index in marmosetlist
            marmosetid=cell2mat(app.marmosetlist.marmosetlist(ind,4));
            app.MarmosetMatchingRegionsTextArea.Value=app.marmosetlist.marmosetlist{ind,3};
            app.StatusLamp.Color='y';
            drawnow()
            %% look for matching areas in LUT
            app.SelectregionDropDown_mouse.Value="";
            app.MouseRegionTextArea.Value="";
            animalLUT = app.LUT.LUT.marmoset;
            %             animalist = app.marmosetlist.marmosetlist;
            Ls=cell(length(marmosetid),1);
            % function "LUTleaves" in "search_in_LUT.m"
            N=size(animalLUT,1);
            loc=zeros(N,1);
            for i=1:N
                if ~isempty(animalLUT{i})
                    % get all children regions of each entry (row) in LUT
                    CC = allchildren(app,app.marmosetlist.marmosetlist,animalLUT{i});
                    loc(i)=sum(ismember(marmosetid,CC)); % search for regionid within all LUT entries
                end
            end
            L=find(loc);
            if isempty(L) % if didn't find in LUT, search children regions of the mouse region
                RR=allchildren(app,app.marmosetlist.marmosetlist,marmosetid);
                RL=cell(length(RR),1);
                for r=1:length(RR) % search each subregion
                    % function "LUTleaves" in "search_in_LUT.m"
                    %                         RL{r}=LUTleaves(primarylist,primaryLUT,RR(r));
                    N=size(animalLUT,1);
                    loc=zeros(N,1);
                    for i=1:N
                        if ~isempty(animalLUT{i})
                            % get all children regions of each entry (row) in LUT
                            CC = allchildren(app,app.marmosetlist.marmosetlist,animalLUT{i});
                            loc(i)=sum(ismember(RR(r),CC)); % search for regionid within all LUT entries
                        end
                    end
                    RL{r}=find(loc);
                end
                L=cell2mat(RL);
                if isempty(L)
                    app.MouseMatchingRegionsTextArea.Value= 'Error: No matching area.';
                else
                    L=unique(L);
                    Ls{i}=L;
                end
            else
                Ls{i}=L;
            end
            Ls=cell2mat(Ls);
            app.L=Ls;
            %% display matching areas
            searchlist=app.mouselist.mouselist;
            searchLUT=app.LUT.LUT.mouse;
            searchid=cell2mat(searchLUT(Ls,1)');
            if ~isempty(searchid)
                %     searchregioninfo=getregioninfo(searchlist,searchid);
                mouseids=cell2mat(searchlist(:,4));
                mouseind=zeros(length(searchid),1);
                mouseregioninfo=cell(length(searchid),size(searchlist,2));
                for i=1:length(searchid)
                    idind=find(mouseids==searchid(i));
                    if isempty(idind)
                        app.MouseMatchingRegionsTextArea.Value= 'Error: Invalid region ID.';
                    elseif length(idind)>1
                        app.MouseMatchingRegionsTextArea.Value= 'Error: Double check region list.';
                    else
                        mouseind(i)=idind;
                        mouseregioninfo(i,:)=searchlist(mouseind(i),:);
                    end
                end
                % display the names
                app.MouseRegionTextArea.Value=join(mouseregioninfo(:,2),',',2);
                app.MouseMatchingRegionsTextArea.Value= join(mouseregioninfo(:,3),',',2);
                %                 searchid1=[];
                %                 for li=1:length(Ls)
                %                     searchid1l=allchildren(searchlist,searchLUT{L(li),1});
                %                     searchid1l=nonzeros(searchid1l);
                %                     if ~isempty(searchid1l)
                %                         searchid1l(:,2)=li;
                %                         searchid1=[searchid1;searchid1l];
                %                     end
                %                 end
            else
                app.MouseMatchingRegionsTextArea.Value= 'Error: No matching area.';
            end
            %% Show region in 3D view (mouse)
            cla(app.Mouse3Dview)
            cla(app.MouseSection)
            cla(app.MouseHierarchy)
            %% Display the brain
            brainvol = app.atlases.mouseanno;
            [X,Y,Z]=size(brainvol);
            [XX,YY,ZZ]=meshgrid(1:Y,1:X,1:Z);
            V=brainvol>0;
            fv=isosurface(V*2,1);
            patch(app.Mouse3Dview,fv,'EdgeColor','none','FaceColor',[.9 .9 .9],'FaceVertexAlphaData',.3,'FaceAlpha','flat','AlphaDataMapping','none');
            axis(app.Mouse3Dview,'tight')
            camlight(app.Mouse3Dview,'right')
            lighting(app.Mouse3Dview,'gouraud')
            daspect(app.Mouse3Dview,app.atlases.mouseres)
            %% highlight identified areas
            % from "match_brain_view.m"
            % to control colors, go through each row in LUT
            if ~contains(app.MouseMatchingRegionsTextArea.Value,'Error')
                mouseidlist1=[];
                for li=1:length(app.L)
                    mousematchid=app.LUT.LUT.mouse{app.L(li),1};
                    if ~isempty(mousematchid)
                        mouseidlist1l=allchildren(app,app.mouselist.mouselist,mousematchid);
                        mouseidlist1l=nonzeros(mouseidlist1l);
                        if ~isempty(mouseidlist1l)
                            mouseidlist1l(:,2)=li;
                            mouseidlist1=[mouseidlist1;mouseidlist1l];
                        end
                    end
                end
                regioncolors=lines;
                % show individual brain regions and assign colors on top of the 3D brain
                idV=zeros(size(app.atlases.mouseanno));
                for i=1:length(mouseidlist1)
                    tmpvol=ismember(app.atlases.mouseanno,mouseidlist1(i));
                    idV=idV+tmpvol*i; % one index for each region
                end
                ifanno=sum(sum(sum(idV)));
                if ifanno>0
                    n=0;
                    for i=1:length(mouseidlist1)
                        n=n+1;
                        %                     brainvis3d(idV==i,h,regioncolors(n,:),mouseres);
                        V=idV==i;
                        fv1=isosurface(V*2,1);
                        patch(app.Mouse3Dview,fv1,'EdgeColor','none','FaceColor',regioncolors(n,:),'FaceVertexAlphaData',.3,'FaceAlpha','flat','AlphaDataMapping','none');
                    end
                    %% display coronal section

                    K1=squeeze(sum(sum(idV,1),2)); % get total voxels for all Z
                    [~,K1i]=max(K1); % find the section with largest area annotated
                    imagesc(app.MouseSection,app.atlases.mousetemplate(:,:,K1i)')
                    colormap(app.MouseSection,'gray')
                    hold(app.MouseSection,'on')
                    line(app.MouseSection, [10,10+5/app.atlases.mouseres(1)],[10 10],'linewidth',2,'color','w') % scale bar of 5mm
                    text(app.MouseSection,10,5,'5mm','color','w')
                    idmask=idV(:,:,K1i)';
                    %% show individual brain regions and assign colors on top of the
                    % coronal section
                    n=0;
                    for i=1:length(mouseidlist1)
                        n=n+1;
                        %                     transparent_overlay(gca,idmask==i,regioncolors(n,:));
                        overimg=idmask==i;
                        overimg=single(overimg)/single(max(max(overimg)));
                        mask=cat(3,ones(size(overimg))*regioncolors(n,1),ones(size(overimg))*regioncolors(n,2),ones(size(overimg))*regioncolors(n,3));
                        hold(app.MouseSection,'on');
                        h=imagesc(app.MouseSection,mask);
                        set(h,'AlphaData',single(overimg)*.5)
                    end
                    axis(app.MouseSection,'image','off')
                end
            end
            %% Show matching 3D view (marmoset)
            cla(app.Marmoset3Dview)
            cla(app.MarmosetSection)
            cla(app.MarmosetHierarchy)
            %% Display the brain
            brainvol = app.atlases.marmosetanno2;
            [X,Y,Z]=size(brainvol);
            [XX,YY,ZZ]=meshgrid(1:Y,1:X,1:Z);
            V=brainvol>0;
            fv=isosurface(V*2,1);
            patch(app.Marmoset3Dview,fv,'EdgeColor','none','FaceColor',[.9 .9 .9],'FaceVertexAlphaData',.3,'FaceAlpha','flat','AlphaDataMapping','none');
            axis(app.Marmoset3Dview,'tight')
            camlight(app.Marmoset3Dview,'right')
            lighting(app.Marmoset3Dview,'gouraud')
            daspect(app.Marmoset3Dview,app.atlases.marmosetres)
            %% highlight identified areas
            % from "match_brain_view.m"
            % to control colors, go through each row in LUT
            marmosetidlist1=[];
            for li=1:length(marmosetid)
                marmosetidlist1l=allchildren(app,app.marmosetlist.marmosetlist,marmosetid(li));
                marmosetidlist1l=nonzeros(marmosetidlist1l);
                if ~isempty(marmosetidlist1l)
                    marmosetidlist1l(:,2)=li;
                    marmosetidlist1=[marmosetidlist1;marmosetidlist1l];
                end
            end
            regioncolors=lines;
            % show individual brain regions and assign colors on top of the 3D brain
            idV=zeros(size(app.atlases.marmosetanno2));
            for i=1:length(marmosetidlist1)
                tmpvol=ismember(app.atlases.marmosetanno2,marmosetidlist1(i));
                idV=idV+tmpvol*i; % one index for each region
            end
            ifanno=sum(sum(sum(idV)));

            if ifanno>0
                n=0;
                for i=1:length(marmosetidlist1)
                    n=n+1;
                    %                     brainvis3d(idV==i,h,regioncolors(n,:),mouseres);
                    V=idV==i;
                    fv1=isosurface(V*2,1);
                    patch(app.Marmoset3Dview,fv1,'EdgeColor','none','FaceColor',regioncolors(n,:),'FaceVertexAlphaData',.3,'FaceAlpha','flat','AlphaDataMapping','none');
                end
                %% display coronal section

                K1=squeeze(sum(sum(idV,1),2)); % get total voxels for all Z
                [~,K1i]=max(K1); % find the section with largest area annotated
                imagesc(app.MarmosetSection,app.atlases.marmosettemplate2(:,:,K1i)')
                colormap(app.MarmosetSection,'gray')
                hold(app.MarmosetSection,'on')
                line(app.MarmosetSection, [10,10+5/app.atlases.marmosetres(1)],[10 10],'linewidth',2,'color','w') % scale bar of 5mm
                text(app.MarmosetSection,10,5,'5mm','color','w')
                idmask=idV(:,:,K1i)';
                %% show individual brain regions and assign colors on top of the
                % coronal section
                n=0;
                for i=1:length(marmosetidlist1)
                    n=n+1;
                    %                     transparent_overlay(gca,idmask==i,regioncolors(n,:));
                    overimg=idmask==i;
                    overimg=single(overimg)/single(max(max(overimg)));
                    mask=cat(3,ones(size(overimg))*regioncolors(n,1),ones(size(overimg))*regioncolors(n,2),ones(size(overimg))*regioncolors(n,3));
                    hold(app.MarmosetSection,'on');
                    h=imagesc(app.MarmosetSection,mask);
                    set(h,'AlphaData',single(overimg)*.5)
                end
                axis(app.MarmosetSection,'image','off')

            end
            %% show hierarchy (mouse)
            if ~contains(app.MouseMatchingRegionsTextArea.Value,'Error')
                mouseidL=cell2mat(app.LUT.LUT.mouse(app.L,1)');
                G1=BGplottree(app,app.mouselist.mouselist,mouseidL);
                h1=plot(app.MouseHierarchy,G1,'Layout','layered');
                h1.NodeFontSize=12;
                h1.LineWidth=2;
                highlight(h1,app.MouseRegionTextArea.Value,'Nodecolor','r')
                highlight(h1,app.MouseRegionTextArea.Value,'NodeLabelColor','r')
            end
            %% show hierarchy (marmoset)
            if ~isempty(app.MarmosetRegionTextArea.Value)
                %                 marmosetidL=cell2mat(app.LUT.LUT.marmoset(app.L,1)');
                G2=BGplottree(app,app.marmosetlist.marmosetlist,marmosetid);
                h2=plot(app.MarmosetHierarchy,G2,'Layout','layered');
                h2.NodeFontSize=12;
                h2.LineWidth=2;
                highlight(h2,app.MarmosetRegionTextArea.Value,'Nodecolor','r')
                highlight(h2,app.MarmosetRegionTextArea.Value,'NodeLabelColor','r')
            end
            app.StatusLamp.Color='g';
        end

        % Button down function: MouseSection
        function Mouse3DviewButtonDown(app, event)


        end

        % Callback function
        function Marmoset3DviewButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Mouse3Dview = uiaxes(app.UIFigure);
            title(app.Mouse3Dview, 'Mouse Brain 3D')
            app.Mouse3Dview.XTick = [];
            app.Mouse3Dview.YTick = [];
            app.Mouse3Dview.ZTick = [];
            app.Mouse3Dview.ButtonDownFcn = createCallbackFcn(app, @MouseRegionTextValueChanging, true);
            app.Marmoset3Dview = uiaxes(app.UIFigure);
            title(app.Marmoset3Dview, 'Marmoset Brain 3D')
            app.Marmoset3Dview.XTick = [];
            app.Marmoset3Dview.YTick = [];
            app.Marmoset3Dview.ZTick = [];
            app.Marmoset3Dview.ButtonDownFcn = createCallbackFcn(app, @MarmosetRegionTextValueChanging, true);
            app.MouseSection = uiaxes(app.UIFigure);
            title(app.MouseSection, 'Mouse sample coronal section')
            app.MouseSection.XTick = [];
            app.MouseSection.YTick = [];
            app.MouseSection.ZTick = [];
            app.MouseSection.ButtonDownFcn = createCallbackFcn(app, @Mouse3DviewButtonDown, true);
            app.MarmosetSection = uiaxes(app.UIFigure);
            title(app.MarmosetSection, 'Marmoset sample coronal section')
            app.MarmosetSection.XTick = [];
            app.MarmosetSection.YTick = [];
            app.MarmosetSection.ZTick = [];
            app.MouseHierarchy = uiaxes(app.UIFigure);
            app.MouseHierarchy.XTick = [];
            app.MouseHierarchy.YTick = [];
            app.MouseHierarchy.ZTick = [];
            app.MarmosetHierarchy = uiaxes(app.UIFigure);
            app.MarmosetHierarchy.XTick = [];
            app.MarmosetHierarchy.YTick = [];
            app.MarmosetHierarchy.ZTick = [];
            app.MouseLabel = uilabel(app.UIFigure);
            app.MouseLabel.Text = 'Mouse';
            app.MarmosetLabel = uilabel(app.UIFigure);
            app.MarmosetLabel.Text = 'Marmoset';
            app.SelectregionDropDownLabel = uilabel(app.UIFigure);
            app.SelectregionDropDownLabel.Text = 'Select region';
            app.SelectregionDropDown_mouse = uidropdown(app.UIFigure);
            app.SelectregionDropDown_mouse.Items = {'grey', 'fiber tracts', 'VS', 'grv', 'retina', 'CH', 'BS', 'CB', 'cm', 'cbf', 'scwm', 'lfbs', 'eps', 'mfbs', 'VL', 'IVF', 'V3', 'AQ', 'V4', 'c', 'grv of CTX', 'grv of CBX', 'IPF', 'CTX', 'CNU', 'IB', 'MB', 'HB', 'CBX', 'CBN', 'tn', 'von', 'In', 'IIn', 'IIIn', 'IVn', 'VIn', 'Vn', 'VIIn', 'VIIIn', 'IXn', 'Xn', 'XIn', 'XIIn', 'vrt', 'drt', 'sst', 'cbc', 'cbp', 'arb', 'cc', 'cst', 'lfbst', 'epsc', 'tsp', 'rust', 'ctb', 'rst', 'vsp', 'mfbc', 'mfsbshy', 'RC', 'SEZ', 'chpl', 'chfl', 'V4r', 'eg', 'hf', 'rf', 'ri', 'pce', 'pcf', 'pri', 'psf', 'ppf', 'sec', 'plf', 'nf', 'sif', 'icf', 'apf', 'ipf', 'pms', 'pfs', 'CTXpl', 'CTXsp', 'STR', 'PAL', 'TH', 'HY', 'MBsen', 'MBmot', 'MBsta', 'P', 'MY', 'CBXmo', 'CBXpu', 'CBXgr', 'VERM', 'HEM', 'FN', 'IP', 'DN', 'VeCB', 'onl', 'lotg', 'aco', 'aot', 'bsc', 'csc', 'och', 'opt', 'ttp', 'mlf', 'pc', 'IVd', 'moV', 'sV', 'iVIIn', 'gVIIn', 'cvb', 'vVIIIn', 'cVIIIn', 'ts', 'cett', 'sttl', 'sttv', 'scrt', 'sop', 'srp', 'svp', 'stp', 'shp', 'step', 'scp', 'mcp', 'icp', 'tct', 'fa', 'ee', 'ccg', 'fp', 'ccr', 'ccb', 'ccs', 'int', 'cpd', 'cte', 'crt', 'cpt', 'cbt', 'py', 'pyd', 'cstc', 'cstu', 'em', 'im', 'mtc', 'tp', 'or', 'ar', 'pap', 'nst', 'ntt', 'ptf', 'snp', 'stf', 'tspd', 'dtd', 'tspc', 'vtd', 'rrt', 'rstl', 'rstm', 'amc', 'apd', 'act', 'cing', 'fxs', 'lab', 'st', 'mfb', 'vlt', 'poc', 'sup', 'pmx', 'smd', 'php', 'pvbh', 'mfbsma', 'mfbst', 'mfbse', 'mfbsm', 'Isocortex', 'OLF', 'HPF', '6b', 'CLA', 'EP', 'LA', 'BLA', 'BMA', 'PA', 'STRd', 'STRv', 'LSX', 'sAMY', 'PALd', 'PALv', 'PALm', 'PALc', 'DORsm', 'DORpm', 'PVZ', 'PVR', 'MEZ', 'LZ', 'ME', 'SCs', 'IC', 'NB', 'SAG', 'PBG', 'MEV', 'SCO', 'SNr', 'VTA', 'PN', 'RR', 'MRN', 'SCm', 'PAG', 'PRT', 'InCo', 'CUN', 'RN', 'III', 'MA3', 'EW', 'IV', 'Pa4', 'VTN', 'AT', 'LT', 'DT', 'MT', 'SNl', 'SNc', 'PPN', 'RAmb', 'P-sen', 'P-mot', 'P-sat', 'MY-sen', 'MY-mot', 'MY-sat', 'LING', 'CENT', 'CUL', 'DEC', 'FOTU', 'PYR', 'UVU', 'NOD', 'SIM', 'AN', 'PRM', 'COPY', 'PFL', 'FL', 'lot', 'lotd', 'aolt', 'mtV', 'sptV', 'tb', 'ias', 'das', 'll', 'cic', 'bic', 'dl', 'dcm', 'vc', 'fpr', 'dc', 'ml', 'hht', 'dscp', 'uf', 'sctv', 'sctd', 'cct', 'jrb', 'bct', 'ec', 'alv', 'df', 'fi', 'fxprg', 'fxpo', 'hc', 'per', 'ab', 'stc', 'supa', 'supd', 'supv', 'phpd', 'phpl', 'phpm', 'phpv', 'pm', 'mtt', 'mtg', 'mp', 'pvbt', 'sm', 'fr', 'hbc', 'PIS', 'dlf', 'dtt', 'FRP', 'MO', 'SS', 'GU', 'VISC', 'AUD', 'VIS', 'ACA', 'PL', 'ILA', 'ORB', 'AI', 'RSP', 'PTLp', 'TEa', 'PERI', 'ECT', 'MOB', 'AOB', 'AON', 'TT', 'DP', 'PIR', 'NLOT', 'COA', 'PAA', 'TR', 'HIP', 'RHP', 'EPd', 'EPv', 'BLAa', 'BLAp', 'BLAv', 'BMAa', 'BMAp', 'CP', 'ACB', 'FS', 'OT', 'LSS', 'LS', 'SF', 'SH', 'AAA', 'BA', 'CEA', 'IA', 'MEA', 'GPe', 'GPi', 'SI', 'MA', 'MSC', 'TRS', 'BST', 'BAC', 'VENT', 'SPF', 'SPA', 'PP', 'GENd', 'LAT', 'ATN', 'MED', 'MTN', 'ILM', 'RT', 'GENv', 'EPI', 'SO', 'ASO', 'PVH', 'PVa', 'PVi', 'ARH', 'ADP', 'AHA', 'AVP', 'AVPV', 'DMH', 'MEPO', 'MPO', 'OV', 'PD', 'PS', 'PSCH', 'PVp', 'PVpo', 'SBPV', 'SCH', 'SFO', 'VMPO', 'VLPO', 'AHN', 'MBO', 'MPN', 'PMd', 'PMv', 'PVHd', 'VMH', 'PH', 'LHA', 'LPO', 'PST', 'PSTN', 'PeF', 'RCH', 'STN', 'TU', 'ZI', 'SCop', 'SCsg', 'SCzo', 'ICc', 'ICd', 'ICe', 'MRNm', 'MRNmg', 'MRNp', 'SCdg', 'SCdw', 'SCiw', 'SCig', 'PRC', 'INC', 'ND', 'Su3', 'APN', 'MPT', 'NOT', 'NPC', 'OP', 'PPT', 'RPF', 'IF', 'IPN', 'RL', 'CLI', 'DR', 'NLL', 'PSV', 'PB', 'SOC', 'B', 'DTN', 'LTN', 'PDTg', 'PCG', 'PG', 'PRNc', 'PRNv', 'SG', 'SSN', 'SUT', 'TRN', 'V', 'P5', 'Acs5', 'PC5', 'I5', 'CS', 'LC', 'LDT', 'NI', 'PRNr', 'RPO', 'SLC', 'SLD', 'AP', 'CN', 'DCN', 'ECU', 'NTB', 'NTS', 'SPVC', 'SPVI', 'SPVO', 'Pa5', 'z', 'VI', 'ACVI', 'VII', 'ACVII', 'EV', 'AMB', 'DMX', 'ECO', 'GRN', 'ICB', 'IO', 'IRN', 'ISN', 'LIN', 'LRN', 'MARN', 'MDRN', 'PARN', 'PAS', 'PGRN', 'PHY', 'PMR', 'PPY', 'VNC', 'x', 'XII', 'y', 'INV', 'RM', 'RPA', 'RO', 'LINGmo', 'LINGpu', 'LINGgr', 'CENT2', 'CENT3', 'CUL4', 'CUL5', '"CUL4, 5"', 'DECmo', 'DECpu', 'DECgr', 'FOTUmo', 'FOTUpu', 'FOTUgr', 'PYRmo', 'PYRpu', 'PYRgr', 'UVUmo', 'UVUpu', 'UVUgr', 'NODmo', 'NODpu', 'NODgr', 'SIMmo', 'SIMpu', 'SIMgr', 'ANcr1', 'ANcr2', 'PRMmo', 'PRMpu', 'PRMgr', 'COPYmo', 'COPYpu', 'COPYgr', 'PFLmo', 'PFLpu', 'PFLgr', 'FLmo', 'FLpu', 'FLgr', 'cuf', 'grf', 'iaf', 'sct', 'oct', 'rct', 'db', 'mct', 'fx', 'dhc', 'vhc', 'FRP1', 'FRP2/3', 'FRP5', 'FRP6a', 'FRP6b', 'MO1', 'MO2/3', 'MO5', 'MO6a', 'MO6b', 'MOp', 'MOs', 'SS1', 'SS2/3', 'SS4', 'SS5', 'SS6a', 'SS6b', 'SSp', 'SSs', 'GU1', 'GU2/3', 'GU4', 'GU5', 'GU6a', 'GU6b', 'VISC1', 'VISC2/3', 'VISC4', 'VISC5', 'VISC6a', 'VISC6b', 'AUDd', 'AUDp', 'AUDpo', 'AUDv', 'VIS1', 'VIS2/3', 'VIS4', 'VIS5', 'VIS6a', 'VIS6b', 'VISal', 'VISam', 'VISl', 'VISp', 'VISpl', 'VISpm', 'VISli', 'VISpor', 'ACA1', 'ACA2/3', 'ACA5', 'ACA6a', 'ACA6b', 'ACAd', 'ACAv', 'PL1', 'PL2', 'PL2/3', 'PL5', 'PL6a', 'PL6b', 'ILA1', 'ILA2', 'ILA2/3', 'ILA5', 'ILA6a', 'ILA6b', 'ORB1', 'ORB2/3', 'ORB5', 'ORB6a', 'ORB6b', 'ORBl', 'ORBm', 'ORBv', 'ORBvl', 'AId', 'AIp', 'AIv', 'RSPagl', 'RSPd', 'RSPv', 'PTLp1', 'PTLp2/3', 'PTLp4', 'PTLp5', 'PTLp6a', 'PTLp6b', 'VISa', 'VISrl', 'TEa1', 'TEa2/3', 'TEa4', 'TEa5', 'TEa6a', 'TEa6b', 'PERI1', 'PERI2/3', 'PERI5', 'PERI6a', 'PERI6b', 'ECT1', 'ECT2/3', 'ECT5', 'ECT6a', 'ECT6b', 'MOBgl', 'MOBgr', 'MOBipl', 'MOBmi', 'MOBopl', 'AOBgl', 'AOBgr', 'AOBmi', 'AONd', 'AONe', 'AONl', 'AONm', 'AONpv', 'AON1', 'AON2', 'TTd', 'TTv', 'DP1', 'DP2', 'DP2/3', 'DP5', 'DP6a', 'PIR1-3', 'PIR1', 'PIR2', 'PIR3', 'NLOT1-3', 'NLOT1', 'NLOT2', 'NLOT3', 'COAa', 'COAp', 'PAA1-3', 'PAA1', 'PAA2', 'PAA3', 'TR1-3', 'TR1', 'TR2', 'TR3', 'CA', 'DG', 'FC', 'IG', 'ENT', 'PAR', 'POST', 'PRE', 'SUB', 'ProS', 'HATA', 'APr', 'isl', 'islm', 'OT1-3', 'OT1', 'OT2', 'OT3', 'LSc', 'LSr', 'LSv', 'CEAc', 'CEAl', 'CEAm', 'MEAad', 'MEAav', 'MEApd', 'MEApv', 'MS', 'NDB', 'BSTa', 'BSTp', 'VAL', 'VM', 'VP', 'PoT', 'SPFm', 'SPFp', 'MG', 'LGd', 'LP', 'PO', 'POL', 'SGN', 'Eth', 'REth', 'AV', 'AM', 'AD', 'IAM', 'IAD', 'LD', 'IMD', 'MD', 'SMT', 'PR', 'PVT', 'PT', 'RE', 'Xi', 'RH', 'CM', 'PCN', 'CL', 'PF', 'PIL', 'IGL', 'IntG', 'LGv', 'SubG', 'MH', 'LH', 'PIN', 'NC', 'PVHm', 'PVHp', 'DMHa', 'DMHp', 'DMHv', 'AHNa', 'AHNc', 'AHNd', 'AHNp', 'LM', 'MM', 'SUM', 'TM', 'MPNc', 'MPNl', 'MPNm', 'PVHdp', 'PVHf', 'PVHlp', 'PVHmpv', 'VMHa', 'VMHc', 'VMHdm', 'VMHvl', 'A13', 'FF', 'SCig-a', 'SCig-b', 'SCig-c', 'IPR', 'IPC', 'IPA', 'IPL', 'IPI', 'IPDM', 'IPDL', 'IPRL', 'NLLd', 'NLLh', 'NLLv', 'KF', 'PBl', 'PBm', 'POR', 'SOCm', 'SOCl', 'CSl', 'CSm', 'CNlam', 'CNspg', 'DCO', 'VCO', 'CU', 'GR', 'NTSce', 'NTSco', 'NTSge', 'NTSl', 'NTSm', 'SPVOcdm', 'SPVOmdmd', 'SPVOmdmv', 'SPVOrdm', 'SPVOvl', 'AMBd', 'AMBv', 'LRNm', 'LRNp', 'MDRNd', 'MDRNv', 'PGRNd', 'PGRNl', 'NIS', 'NR', 'PRP', 'PPYd', 'PPYs', 'LAV', 'MV', 'SPIV', 'SUV', 'CENT2mo', 'CENT2pu', 'CENT2gr', 'CENT3mo', 'CENT3pu', 'CENT3gr', 'CUL4mo', 'CUL4pu', 'CUL4gr', 'CUL5mo', 'CUL5pu', 'CUL5gr', '"CUL4, 5mo"', '"CUL4, 5pu"', '"CUL4, 5gr"', 'ANcr1mo', 'ANcr1pu', 'ANcr1gr', 'ANcr2mo', 'ANcr2pu', 'ANcr2gr', 'MOp1', 'MOp2/3', 'MOp5', 'MOp6a', 'MOp6b', 'MOs1', 'MOs2/3', 'MOs5', 'MOs6a', 'MOs6b', 'SSp1', 'SSp2/3', 'SSp4', 'SSp5', 'SSp6a', 'SSp6b', 'SSp-n', 'SSp-bfd', 'SSp-ll', 'SSp-m', 'SSp-ul', 'SSp-tr', 'SSp-un', 'SSs1', 'SSs2/3', 'SSs4', 'SSs5', 'SSs6a', 'SSs6b', 'AUDd1', 'AUDd2/3', 'AUDd4', 'AUDd5', 'AUDd6a', 'AUDd6b', 'VISlla', 'AUDp1', 'AUDp2/3', 'AUDp4', 'AUDp5', 'AUDp6a', 'AUDp6b', 'AUDpo1', 'AUDpo2/3', 'AUDpo4', 'AUDpo5', 'AUDpo6a', 'AUDpo6b', 'AUDv1', 'AUDv2/3', 'AUDv4', 'AUDv5', 'AUDv6a', 'AUDv6b', 'VISal1', 'VISal2/3', 'VISal4', 'VISal5', 'VISal6a', 'VISal6b', 'VISam1', 'VISam2/3', 'VISam4', 'VISam5', 'VISam6a', 'VISam6b', 'VISl1', 'VISl2/3', 'VISl4', 'VISl5', 'VISl6a', 'VISl6b', 'VISp1', 'VISp2/3', 'VISp4', 'VISp5', 'VISp6a', 'VISp6b', 'VISpl1', 'VISpl2/3', 'VISpl4', 'VISpl5', 'VISpl6a', 'VISpl6b', 'VISpm1', 'VISpm2/3', 'VISpm4', 'VISpm5', 'VISpm6a', 'VISpm6b', 'VISli1', 'VISli2/3', 'VISli4', 'VISli5', 'VISli6a', 'VISli6b', 'VISpor1', 'VISpor2/3', 'VISpor4', 'VISpor5', 'VISpor6a', 'VISpor6b', 'ACAd1', 'ACAd2/3', 'ACAd5', 'ACAd6a', 'ACAd6b', 'ACAv1', 'ACAv2/3', 'ACAv5', 'ACAv6a', 'ACAv6b', 'ORBl1', 'ORBl2/3', 'ORBl5', 'ORBl6a', 'ORBl6b', 'ORBm1', 'ORBm2', 'ORBm2/3', 'ORBm5', 'ORBm6a', 'ORBm6b', 'ORBvl1', 'ORBvl2/3', 'ORBvl5', 'ORBvl6a', 'ORBvl6b', 'AId1', 'AId2/3', 'AId5', 'AId6a', 'AId6b', 'AIp1', 'AIp2/3', 'AIp5', 'AIp6a', 'AIp6b', 'AIv1', 'AIv2/3', 'AIv5', 'AIv6a', 'AIv6b', 'RSPagl1', 'RSPagl2/3', 'RSPagl5', 'RSPagl6a', 'RSPagl6b', 'VISmma', 'VISmmp', 'VISm', 'RSPd1', 'RSPd2/3', 'RSPd4', 'RSPd5', 'RSPd6a', 'RSPd6b', 'RSPv1', 'RSPv2', 'RSPv2/3', 'RSPv5', 'RSPv6a', 'RSPv6b', 'VISa1', 'VISa2/3', 'VISa4', 'VISa5', 'VISa6a', 'VISa6b', 'VISrl1', 'VISrl2/3', 'VISrl4', 'VISrl5', 'VISrl6a', 'VISrl6b', 'TTd1-4', 'TTd1', 'TTd2', 'TTd3', 'TTd4', 'TTv1-3', 'TTv1', 'TTv2', 'TTv3', 'COAa1', 'COAa2', 'COAa3', 'COApl', 'COApm', 'CA1', 'CA2', 'CA3', 'DG-mo', 'DG-po', 'DG-sg', 'DG-sgz', 'DGcr', 'DGlb', 'DGmb', 'ENTl', 'ENTm', 'ENTmv', 'PAR1', 'PAR2', 'PAR3', 'POST1', 'POST2', 'POST3', 'PRE1', 'PRE2', 'PRE3', 'SUBd', 'SUBv', 'ProSd', 'ProSv', 'MEApd-a', 'MEApd-b', 'MEApd-c', 'BSTal', 'BSTam', 'BSTdm', 'BSTfu', 'BSTju', 'BSTmg', 'BSTov', 'BSTrh', 'BSTv', 'BSTd', 'BSTpr', 'BSTif', 'BSTtr', 'BSTse', 'VPL', 'VPLpc', 'VPM', 'VPMpc', 'MGd', 'MGv', 'MGm', 'LGd-sh', 'LGd-co', 'LGd-ip', 'AMd', 'AMv', 'MDc', 'MDl', 'MDm', 'LGvl', 'LGvm', 'PVHam', 'PVHmm', 'PVHpm', 'PVHap', 'PVHmpd', 'PVHpv', 'Mmme', 'Mml', 'Mmm', 'Mmp', 'Mmd', 'SUMl', 'SUMm', 'TMd', 'TMv', 'PBlc', 'PBld', 'PBle', 'PBls', 'PBlv', 'PBme', 'PBmm', 'PBmv', 'SSp-n1', 'SSp-n2/3', 'SSp-n4', 'SSp-n5', 'SSp-n6a', 'SSp-n6b', 'SSp-bfd1', 'SSp-bfd2/3', 'SSp-bfd4', 'SSp-bfd5', 'SSp-bfd6a', 'SSp-bfd6b', 'VISrll', 'SSp-ll1', 'SSp-ll2/3', 'SSp-ll4', 'SSp-ll5', 'SSp-ll6a', 'SSp-ll6b', 'SSp-m1', 'SSp-m2/3', 'SSp-m4', 'SSp-m5', 'SSp-m6a', 'SSp-m6b', 'SSp-ul1', 'SSp-ul2/3', 'SSp-ul4', 'SSp-ul5', 'SSp-ul6a', 'SSp-ul6b', 'SSp-tr1', 'SSp-tr2/3', 'SSp-tr4', 'SSp-tr5', 'SSp-tr6a', 'SSp-tr6b', 'SSp-un1', 'SSp-un2/3', 'SSp-un4', 'SSp-un5', 'SSp-un6a', 'SSp-un6b', 'VISlla1', 'VISlla2/3', 'VISlla4', 'VISlla5', 'VISlla6a', 'VISlla6b', 'VISmma1', 'VISmma2/3', 'VISmma4', 'VISmma5', 'VISmma6a', 'VISmma6b', 'VISmmp1', 'VISmmp2/3', 'VISmmp4', 'VISmmp5', 'VISmmp6a', 'VISmmp6b', 'VISm1', 'VISm2/3', 'VISm4', 'VISm5', 'VISm6a', 'VISm6b', 'COApl1-2', 'COApl1-3', 'COApl1', 'COApl2', 'COApl3', 'COApm1-2', 'COApm1-3', 'COApm1', 'COApm2', 'COApm3', 'CA1slm', 'CA1so', 'CA1sp', 'CA1sr', 'CA2slm', 'CA2so', 'CA2sp', 'CA2sr', 'CA3slm', 'CA3slu', 'CA3so', 'CA3sp', 'CA3sr', 'DGcr-mo', 'DGcr-po', 'DGcr-sg', 'DGlb-mo', 'DGlb-po', 'DGlb-sg', 'DGmb-mo', 'DGmb-po', 'DGmb-sg', 'ENTl1', 'ENTl2', 'ENTl2/3', 'ENTl2a', 'ENTl2b', 'ENTl3', 'ENTl4', 'ENTl4/5', 'ENTl5', 'ENTl5/6', 'ENTl6a', 'ENTl6b', 'ENTm1', 'ENTm2', 'ENTm2a', 'ENTm2b', 'ENTm3', 'ENTm4', 'ENTm5', 'ENTm5/6', 'ENTm6', 'ENTmv1', 'ENTmv2', 'ENTmv3', 'ENTmv4', 'ENTmv5/6', 'SUBd-m', 'SUBd-sp', 'SUBd-sr', 'SUBv-m', 'SUBv-sp', 'SUBv-sr', 'ProSd-m', 'ProSd-sp', 'ProSd-sr', 'ProSv-m', 'ProSv-sp', 'Prosv-sr', 'PVHpml', 'PVHpmm', 'VISrll1', 'VISrll2/3', 'VISrll4', 'VISrll5', 'VISrll6a', 'VISrll6b'};
            app.SelectregionDropDown_mouse.Editable = 'on';
            app.SelectregionDropDown_mouse.ValueChangedFcn = createCallbackFcn(app, @MouseRegionTextValueChanging, true);
            app.SelectregionDropDown_mouse.Value = 'grey';
            app.SelectregionDropDown_2Label = uilabel(app.UIFigure);
            app.SelectregionDropDown_2Label.Text = 'Select region';
            app.SelectregionDropDown_marmoset = uidropdown(app.UIFigure);
            app.SelectregionDropDown_marmoset.Items = {'WHOLE', 'FORE', 'TEL', 'CER', 'CorA', 'AntC', 'A24a', 'A24b', 'A24c', 'A24d', 'A', 'AuAL', 'AuCL', 'AuCM', 'AuCPB', 'AuML', 'AuA1', 'AuR', 'AuRPB', 'AuRM', 'AuRTL', 'AuRTM', 'AuRT', 'STR', 'TPt', 'DP', 'A10', 'A46D', 'A46V', 'A8aD', 'A8aV', 'A8b', 'A9', 'Ins', 'AI', 'DI', 'GI', 'IPro', 'PaIL', 'PaIM', 'ReI', 'TPro', 'LIT', 'PGa/IPa', 'TE1', 'TE2', 'TE3', 'TEO', 'TPO', 'TPPro', 'MedP', 'A14C', 'A14R', 'A25', 'A32', 'A32V', 'OF', 'A11', 'A13a', 'A13b', 'A13L', 'A13M', 'Gu', 'OPAl', 'OPro', 'PCMR', 'A23a', 'A23b', 'A23c', 'A23V', 'A29a-c', 'A29d', 'A30', 'A31', 'PGM', 'ProSt', 'PostP', 'AIP', 'LIP', 'MIP', 'OPt', 'PE', 'PEC', 'PF', 'PFG', 'PG', 'VIP', 'MotPre', 'A4ab', 'A4c', 'A6DC', 'A6DR', 'A6M', 'A6Va', 'A6Vb', 'A8C', 'Soma', 'A1/2', 'A3a', 'A3b', 'S2E', 'S2I', 'S2PR', 'S2PV', 'Vis', 'A19DI', 'A19M', 'FST', 'MST', 'V1', 'V2', 'V3', 'V3A', 'V4', 'V4T', 'V5', 'V6', 'V6A', 'VentPre', 'A45', 'A47L', 'A47M', 'A47O', 'ProM', 'VT', 'A35', 'A36', 'Ent', 'TF', 'TFO', 'TH', 'TL', 'TLO', 'FC', 'IG', 'Nv', 'PaS', 'Pir', 'Pir 1', 'Pir 2', 'Pir 3', 'PrS', 'ProS', 'SS', 'OlfC', 'TT', 'Tu', 'ICj', 'ICjM', 'HipF', 'DG', 'GrDG', 'MoDG', 'PoDG', 'Hip', 'LMol', 'Or', 'Py', 'Rad', 'SLu', 'CA1', 'CA2', 'CA3', 'S', 'OLB', 'OB', 'AOB', 'AON', 'E', 'EPlA', 'EPl', 'GlA', 'Gl', 'GrA', 'GrO', 'IPl', 'MiA', 'Mi', 'ON', 'SEP', 'HDB', 'VDB', 'Ld', 'LDB', 'LS', 'LSD', 'LSI', 'LSV', 'MS', 'PDZ', 'SFi', 'SHi', 'TS', 'BG', 'St', 'Cd', 'Pu', 'Acb', 'AcbC', 'AcbSh', 'MAcbSh', 'DAcbSh', 'LAcbSh', 'AMG', 'Lbn', 'La', 'BM', 'BMVM', 'BMD', 'BMMC', 'BMPC', 'BMV', 'BL', 'BLI', 'BLVL', 'BLVM', 'BLD', 'BLDL', 'AHi', 'APir', 'ASt', 'CoAG', 'Co', 'AA', 'ACo', 'LOT', 'PCo', 'Ce', 'CeC', 'CeL', 'CeM', 'Me', 'PaL', 'CxA', 'EXA', 'EA', 'ST', 'STMV', 'Fu', 'STIA', 'STLD', 'STLP', 'STMA', 'STMP', 'I', 'IM', 'IPAC', 'STh', 'VEn', 'VP', 'B', 'Cl', 'DEn', 'GP', 'IGP', 'EGP', 'IEn', 'SIB', 'SNBG', 'SNL', 'SNR', 'SNC', 'SNCD', 'SNCM', 'SNCV', 'DIEN', 'HYPO', 'SO', 'SOR', 'VTM', 'VLPO', 'VMH', 'VMPO', 'AHA', 'AH', 'AVPe', 'Arc', 'DA11', 'DA12', 'DA14', 'DM', 'DMC', 'JPLH', 'Lter', 'LH', 'MB', 'LM', 'ML', 'MM', 'MTu', 'SM', 'Pa', 'PaDC', 'PaLM', 'PaPo', 'PeF', 'Pe', 'PH', 'PMD', 'PMV', 'PO', 'LPO', 'MPA', 'MPO', 'MnPO', 'RM', 'StHy', 'SCh', 'THAL', 'ASN', 'LTN', 'LD', 'LP', 'Pul', 'APul', 'IPul', 'IPulCL', 'IPulCM', 'IPulM', 'IPulP', 'LPul', 'MPul', 'MD', 'MDC', 'MDL', 'MDM', 'ANN', 'AD', 'AM', 'AV', 'PT', 'MTT', 'VA', 'VAL', 'VAMC', 'VAM', 'VL', 'VLM', 'VLD', 'VLV', 'VLLa', 'VST', 'DLG', 'ExMC', 'ExPC', 'InMC', 'InPC', 'K1', 'K2', 'K3', 'K4', 'PrG', 'SST', 'Po', 'Eth', 'PoT', 'SG', 'VPI', 'VPS', 'VPC', 'VPPC', 'VPL', 'VPM', 'AuT', 'MG', 'MGD', 'MGM', 'MGV', 'MIN', 'OPC', 'PC', 'PaF', 'PIL', 'PLi', 'RRe', 'Re', 'Rh', 'Xi', 'Ang', 'CM', 'CL', 'CMn', 'IAM', 'IMD', 'VNT', 'Rt', 'SubG', 'SubI', 'SPF', 'SPFPC', 'ZI', 'ZIR', 'ZIV', 'ZIC', 'ZID', 'PSTh', 'Pt', 'APT', 'MCPC', 'MPT', 'OT', 'OPT', 'PrC', 'ReC', 'EPI', 'PV', 'PVA', 'PVP', 'Hb', 'MHb', 'LHb', 'SUL', 'cal', 'hif', 'ips', 'lf', 'ots', 'orbs', 'plf', 'pcuf', 'ppf', 'prf', 'rf', 'sf', 'sts', 'vmf', 'CN', '6n', '8cn', '7n', '12n', '7ni', '3n', '2n', '5n', '4n', '8vn', '8n', 'STEM', 'MED', '11N', 'Ad1', 'Amb', 'AmbC', 'AmbL', 'Bo', 'Ct', 'Cu', '10Ca', 'EF', 'ECu', 'Gr', '12N', '12GH', 'IO', 'IOBe', 'IOK', 'IOD', 'IOM', 'IOPr', 'IOA', 'IOB', 'IOC', 'IS', 'In', 'I8', 'KF', 'LPCu', 'LT', 'Li', 'Mx', 'MnA', 'NA1', 'NA2', 'Nt', 'Ro', 'Sp5MED', 'Sp5I', 'Sp5O', 'Sp5C', 'X', 'PSol', 'Pa5', 'Pr', 'PrMC', 'RaMED', 'ROb', 'RPa', 'RtMED', 'PCRtA', 'RVL', 'CVL', 'DPGi', 'Gi', 'GiA', 'GiV', 'IRt', 'LPGi', 'LRt', 'LRtPC', 'LRtS5', 'MdD', 'MdV', 'PCRt', 'RAmb', 'RVRG', 'Sol', 'SolC', 'SolDL', 'SolG', 'SolIM', 'SolI', 'SolM', 'SolPaC', 'SolRL', 'SolV', 'SolVL', 'SuS', 'VeMED', 'LVe', 'MVe', 'MVeMC', 'MVePC', 'Y', 'SpVe', 'SuVe', '10N', 'PONS', 'RaPONS', 'CLi', 'DRC', 'MnR', 'PMnR', 'RIP', 'RMg', 'Rbd', 'RtPONS', 'PnC', 'PnO', 'PnV', 'RtTg', 'RtTgL', 'RIs', 'RL', 'Sag', 'SubCD', 'SubCV', 'SPTg', 'SOl', 'DPO', 'LSO', 'LVPO', 'MSO', 'MVPO', 'SPO', 'Su5', 'TrLL', 'VLL', 'VePONS', '6N', 'ATg', 'B9', 'Bar', 'CG', 'C', 'DC', 'GrC', 'VCA', 'VCP', 'DLL', 'DTg', '7N', '7SH', 'ILL', 'LC', 'LDTg', 'LDTgV', 'PB', 'MPB', 'LPB', 'LPBC', 'LPBCr', 'LPBD', 'LPBE', 'LPBI', 'LPBS', 'LPBV', 'MPBE', 'MPL', 'MiTg', '5N', '5PC', 'NA5', 'NA7', 'CAT', 'Sp5PONS', 'Tz', 'PBG', 'PL', 'PTg', 'P5', 'Pn', 'PDTg', 'Pr5', 'Pr5DM', 'Pr5VL', 'MID', 'Com', 'DA8', 'Dk', 'EW', 'I3', 'IC', 'ECIC', 'CIC', 'DCIC', 'InC', 'IP', 'IPA', 'IPC', 'IPI', 'IPL', 'IPR', 'MA3', 'Me5', 'BIC', 'PCom', '3N', '3PC', 'PAG', 'DLPAG', 'DMPAG', 'LPAG', 'PlPAG', 'Su3C', 'Su3', 'VLPAG', 'PBP', 'PIF', 'PN', 'PP', 'PR', 'R', 'RMC', 'RPC', 'RaMID', 'RLi', 'DR', 'DRC', 'DRD', 'DRI', 'DRL', 'DRV', 'DRVL', 'RtMID', 'CnF', 'mRt', 'PrCnF', 'RI', 'SC', 'DpG', 'DpWh', 'InG', 'InWh', 'Op', 'SuG', 'Zo', 'SubB', 'SNMID', '4N', 'VTA', 'VTAC', 'VTAR', 'CvO', 'AP', 'ME', 'Nhyp', 'Pi', 'SCO', 'SFO', 'VOLT', 'GH', 'FB', 'al', 'alv', 'ac', 'aca', 'aci', 'acp', 'asc7', 'bic', 'bsc', 'cp', 'cg', 'cc', 'cst', 'cic', 'cll', 'csc', 'cu', 'xml', 'xscp', 'das', 'dcs', 'dsc', 'dtgx', 'ec', 'eml', 'ex', 'fr', 'fi', 'fmi', 'f', 'gcc', 'g7', 'gr', 'h1', 'h2', 'hbc', 'icp', 'ic', 'lcs', 'll', 'lml', 'lo', 'lfp', 'mp', 'mtg', 'mt', 'mfb', 'ml', 'mlf', 'mml', 'me5', 'mcp', 'm5', 'ns', 'oc', 'ocb', 'och', 'opt', 'palhy', 'pc', 'py', 'pyx', 'rmx', 'rcc', 'rs', 's5', 'sol', 'sp5', 'sm', 'st', 'sox', 'scp', 'ts', 'tfp', 'tz', 'vhc', 'vsc', 'vtgx', 'veme', 'V', 'Mre', 'ReIC', '3V', 'Aq', 'CC', 'D3V', '4V', 'IVF', 'LR4V', 'LV', 'CB', 'Cb', '2Cb', '3Cb', '4Cb', '5Cb', '6Cb', '7Cb', '8Cb', '9Cb', '10Cb', 'PFl', 'PM', 'Sim', 'Cop', 'Crus1', 'Crus2', 'Fl', '1Cb', 'SMV', 'Dc', 'IntA', 'Lat', 'LatPC', 'Med', 'IntP', 'VeCb'};
            app.SelectregionDropDown_marmoset.Editable = 'on';
            app.SelectregionDropDown_marmoset.ValueChangedFcn = createCallbackFcn(app, @MarmosetRegionTextValueChanging, true);
            app.SelectregionDropDown_marmoset.Value = 'WHOLE';
            app.MouseRegionTextAreaLabel = uilabel(app.UIFigure);
            app.MouseRegionTextAreaLabel.Text = 'Mouse Region';
            app.MouseRegionTextArea = uitextarea(app.UIFigure);
            app.MouseRegionTextArea.ValueChangingFcn = createCallbackFcn(app, @MouseRegionTextValueChanging, true);
            app.MouseRegionTextArea.Editable = 'off';
            app.MarmosetRegionTextAreaLabel = uilabel(app.UIFigure);
            app.MarmosetRegionTextAreaLabel.Text = 'Marmoset Region';
            app.MarmosetRegionTextArea = uitextarea(app.UIFigure);
            app.MarmosetRegionTextArea.ValueChangingFcn = createCallbackFcn(app, @MarmosetRegionTextValueChanging, true);
            app.MarmosetRegionTextArea.Editable = 'off';
            app.MouseMatchingRegionsTextAreaLabel = uilabel(app.UIFigure);
            app.MouseMatchingRegionsTextAreaLabel.Text = 'Mouse Matching Region(s)';
            app.MouseMatchingRegionsTextArea = uitextarea(app.UIFigure);
            app.MouseMatchingRegionsTextArea.ValueChangingFcn = createCallbackFcn(app, @MouseRegionTextValueChanging, true);
            app.MouseMatchingRegionsTextArea.Editable = 'off';
            app.MarmosetMatchingRegionsTextAreaLabel = uilabel(app.UIFigure);
            app.MarmosetMatchingRegionsTextAreaLabel.Text = 'Marmoset Matching Region(s)';
            app.MarmosetMatchingRegionsTextArea = uitextarea(app.UIFigure);
            app.MarmosetMatchingRegionsTextArea.ValueChangingFcn = createCallbackFcn(app, @MarmosetRegionTextValueChanging, true);
            app.MarmosetMatchingRegionsTextArea.Editable = 'off';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = marmoset_mouse_region_compare_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end